classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SAMGUI                 matlab.ui.Figure
        ConnectionLamp         matlab.ui.control.Lamp
        ConnectionLampLabel    matlab.ui.control.Label
        STARTMONITORINGButton  matlab.ui.control.StateButton
        Image                  matlab.ui.control.Image
        CurrentstateLabel      matlab.ui.control.Label
        UIAxes2                matlab.ui.control.UIAxes
        UIAxes1                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        connection_status
        input_monitoring
    end

    methods (Access = private)

        function updateIconState(app, state)

            switch state
                case 1
                    app.Image.ImageSource = 'Walking.png';
                case 2
                    app.Image.ImageSource = 'Running.png';
                case 3
                    app.Image.ImageSource = 'Driving.png';
                otherwise
                    app.Image.ImageSource = 'Idle.png';
            end

        end

        function updateLink(app,lat,lon)
            app.MapsLocation.URL = strcat("https://www.google.es/maps/@",sprintf("%.7f",lat),",",sprintf("%.7f",lon),",16z?hl=es");
        end
        
        
        function results = main_thread(app)
            prev_input = app.input_monitoring;
            while(1)
                if prev_input ~= app.input_monitoring
                    prev_input = app.input_monitoring;
                    diso(prev_input);
                else
                    % nothing takes place
                end

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Constructor of the GUI
%            app.main_thread();
        end

        % Value changed function: STARTMONITORINGButton
        function STARTMONITORINGButtonValueChanged(app, event)
            value = app.STARTMONITORINGButton.Value;
            app.input_monitoring = value;
            if value == 1
                app.STARTMONITORINGButton.Text = "STOP MONITORING";
            else
                app.STARTMONITORINGButton.Text = "START MONITORING";
            end
            run GUI.mlapp
%             uiopen('GUI/Copy_of_GUI.mlapp');

%             while (1)
%                 pause(100);
%                 disp(app.STARTMONITORINGButton.Value);
%             end
            %while value == 1 % Con while no funciona, deberia ser tipo
            %thread pero no se como hacerlo
%                 last_state = -1;
%                 currentstate = 0;
%                 if last_state ~= currentstate
%                     updateIconState(app,currentstate);
%                     last_state = currentstate;
%                 end
%                 shock = 0; %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 if shock == 1
%                     uialert(app.SAMGUI,'Telegram message sent and calling 112.','Shock detected');
%                 end
%                 lat = 39.492332;
%                 lon = -0.3635125;%
%                 last_lat = 0;
%                 last_lon = 0;
%                 if lat~=last_lat || lat~=last_lon
%                     updateLink(app,lat,lon)
%                 end
            %end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SAMGUI = uifigure('Visible', 'off');
            app.SAMGUI.Name = 'SAM - Shock Alert Monitor - Team 3 - MATHACK 2022';
            app.UIAxes1 = uiaxes(app.SAMGUI);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.SAMGUI);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CurrentstateLabel = uilabel(app.SAMGUI);
            app.CurrentstateLabel.Text = 'Current state:';
            app.Image = uiimage(app.SAMGUI);
            app.STARTMONITORINGButton = uibutton(app.SAMGUI, 'state');
            app.STARTMONITORINGButton.ValueChangedFcn = createCallbackFcn(app, @STARTMONITORINGButtonValueChanged, true);
            app.STARTMONITORINGButton.Text = 'START MONITORING';
            app.ConnectionLampLabel = uilabel(app.SAMGUI);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.SAMGUI);
            % Show the figure after all components are created
            app.SAMGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SAMGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SAMGUI)
        end
    end
end